classdef denoiser < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Image                          matlab.ui.control.Image
        Image_Noisy                    matlab.ui.control.Image
        Image_Final                    matlab.ui.control.Image
        GaussianNoiseStDevSliderLabel  matlab.ui.control.Label
        GaussianNoiseStDevSlider       matlab.ui.control.Slider
        LoadImageButton                matlab.ui.control.Button
        CloseButton                    matlab.ui.control.Button
        OptimizationAlgorithmDropDownLabel  matlab.ui.control.Label
        OptimizationAlgorithmDropDown  matlab.ui.control.DropDown
        RunButton                      matlab.ui.control.Button
        FinalObjectiveValueEditFieldLabel  matlab.ui.control.Label
        FinalObjectiveValueEditField   matlab.ui.control.NumericEditField
        FinalSNREditFieldLabel         matlab.ui.control.Label
        FinalSNREditField              matlab.ui.control.NumericEditField
        LambdaEditFieldLabel           matlab.ui.control.Label
        LambdaEditField                matlab.ui.control.NumericEditField
        NoisyImageLabel                matlab.ui.control.Label
        OriginalImageLabel             matlab.ui.control.Label
        DenoisedImageLabel             matlab.ui.control.Label
        FinalImageDistanceEditFieldLabel_2  matlab.ui.control.Label
    end

    
    properties (Access = private)
        orig_img; % loaded img
        path; % loaded img path
        im_name; % loaded image name
        noisy_img % noisy img
        final_img; % final denoised img
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
            [app.im_name,app.path] = uigetfile('*.jpg');

            if ~isempty([app.path,app.im_name])
                 % load, convert to greyscale 
                app.orig_img = im2gray(imread([app.path,app.im_name]));
%                 [m,n] = size(app.orig_img);
%                 if m > 70 || n > 70
%                     app.orig_img = autoclip(app.orig_img);
%                 end
               
                % show loaded image
                app.Image.ImageSource = repmat(app.orig_img, 1, 1, 3);
                
                % run Gaussian noise addition
                app.GaussianNoiseStDevSliderValueChanged()
            end
        end

        % Value changed function: GaussianNoiseStDevSlider
        function GaussianNoiseStDevSliderValueChanged(app, event)
            if ~isempty([app.path,app.im_name])
                value = app.GaussianNoiseStDevSlider.Value;
                app.noisy_img = add_gaussian_noise(app.orig_img, value);
                
                app.Image_Noisy.ImageSource = repmat(app.noisy_img, 1, 1, 3);

            end
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            UIFigureCloseRequest(app,event);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            value = app.OptimizationAlgorithmDropDown.Value;
            if isempty(app.noisy_img)
                return;
            end
            
            if strcmp(value, 'TV L1 ADMM')
                tic
                [app.final_img, optval] = TV_l1_reg_admm(app.noisy_img, app.LambdaEditField.Value/2);
                toc
            elseif strcmp(value, 'TV L2 Reg')
                % reg const default = 50
                tic
                [app.final_img, optval] = TV_l2_reg(app.noisy_img, app.LambdaEditField.Value);
                toc
            elseif strcmp(value, 'TV L1 Reg')
                % reg const default = 50
                tic
                [app.final_img, optval] = TV_l1_reg(app.noisy_img, app.LambdaEditField.Value);
                toc
            else
                % spin
                disp('Option Unimplemented')
            end
            
            
            if ~isempty(app.final_img)
                app.Image_Final.ImageSource = repmat(app.final_img, 1, 1, 3);
            end
            
            app.FinalObjectiveValueEditField.Value = optval;

            app.FinalSNREditField.Value = calc_snr(app.orig_img, app.final_img); %calc_image_dist(app.orig_img, app.final_img);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Image = uiimage(app.UIFigure);
            app.Image_Noisy = uiimage(app.UIFigure);
            app.Image_Final = uiimage(app.UIFigure);
            app.GaussianNoiseStDevSliderLabel = uilabel(app.UIFigure);
            app.GaussianNoiseStDevSliderLabel.Text = 'Gaussian Noise St. Dev';
            app.GaussianNoiseStDevSlider = uislider(app.UIFigure);
            app.GaussianNoiseStDevSlider.ValueChangedFcn = createCallbackFcn(app, @GaussianNoiseStDevSliderValueChanged, true);
            app.GaussianNoiseStDevSlider.Value = 40;
            app.LoadImageButton = uibutton(app.UIFigure, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.OptimizationAlgorithmDropDownLabel = uilabel(app.UIFigure);
            app.OptimizationAlgorithmDropDownLabel.Text = 'Optimization Algorithm';
            app.OptimizationAlgorithmDropDown = uidropdown(app.UIFigure);
            app.OptimizationAlgorithmDropDown.Items = {'TV L2 Reg', 'TV L1 Reg', 'TV L1 ADMM'};
            app.OptimizationAlgorithmDropDown.Value = 'TV L2 Reg';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.FinalObjectiveValueEditFieldLabel = uilabel(app.UIFigure);
            app.FinalObjectiveValueEditFieldLabel.Text = 'Final Objective Value';
            app.FinalObjectiveValueEditField = uieditfield(app.UIFigure, 'numeric');
            app.FinalObjectiveValueEditField.Editable = 'off';
            app.FinalSNREditFieldLabel = uilabel(app.UIFigure);
            app.FinalSNREditFieldLabel.Text = 'Final SNR';
            app.FinalSNREditField = uieditfield(app.UIFigure, 'numeric');
            app.FinalSNREditField.Editable = 'off';
            app.LambdaEditFieldLabel = uilabel(app.UIFigure);
            app.LambdaEditFieldLabel.Text = 'Lambda';
            app.LambdaEditField = uieditfield(app.UIFigure, 'numeric');
            app.LambdaEditField.Value = 50;
            app.NoisyImageLabel = uilabel(app.UIFigure);
            app.NoisyImageLabel.Text = 'Noisy Image';
            app.OriginalImageLabel = uilabel(app.UIFigure);
            app.OriginalImageLabel.Text = 'Original Image';
            app.DenoisedImageLabel = uilabel(app.UIFigure);
            app.DenoisedImageLabel.Text = 'Denoised Image';
            app.FinalImageDistanceEditFieldLabel_2 = uilabel(app.UIFigure);
            app.FinalImageDistanceEditFieldLabel_2.Text = 'dB';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = denoiser

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end